classdef OpenDiMetApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        DataPanel                 matlab.ui.container.Panel
        DataTable                 matlab.ui.control.Table
        ControlsPanel             matlab.ui.container.Panel
        DownloadSampleDataButton  matlab.ui.control.Button
        DecimalsDropDown          matlab.ui.control.DropDown
        DecimalsDropDownLabel     matlab.ui.control.Label
        DisplayDecimalLabel       matlab.ui.control.Label
        SaveMatButton             matlab.ui.control.Button
        ExportcsvButton           matlab.ui.control.Button
        UnitsDropDown             matlab.ui.control.DropDown
        UnitsDropDownLabel        matlab.ui.control.Label
        UnitLabel                 matlab.ui.control.Label
        NameEditField             matlab.ui.control.EditField
        NameEditFieldLabel        matlab.ui.control.Label
        NameOptionalLabel         matlab.ui.control.Label
        AssociationDropDown       matlab.ui.control.DropDown
        AssociationDropDownLabel  matlab.ui.control.Label
        AssociationCriteriaLabel  matlab.ui.control.Label
        BrowseButton              matlab.ui.control.Button
        FilesLabel                matlab.ui.control.Label
        FilesListBox              matlab.ui.control.ListBox
        FilesListBoxLabel         matlab.ui.control.Label
        FolderEditField           matlab.ui.control.EditField
        FolderEditFieldLabel      matlab.ui.control.Label
        DataFolderLabel           matlab.ui.control.Label
        DetailsTextArea           matlab.ui.control.TextArea
        DetailsTextAreaLabel      matlab.ui.control.Label
        ObjectDetailsLabel        matlab.ui.control.Label
        FitButton                 matlab.ui.control.Button
        GeometryDropDown          matlab.ui.control.DropDown
        GeometryDropDownLabel     matlab.ui.control.Label
        GeometryLabel             matlab.ui.control.Label
        OverlayAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        dataRoot string
        filePath string
        obj
        assoc
        Data double = zeros(0,3)
        HighlightPt
        LastSelectedRow double = []
        RowStyle
        AssocNames   string = ["Least Squares","MinimumTotalDistance", ...
            "ConstrainedLeastSquares", "ConstrainedMinimumTotalDistance", ...
            "Minimax","Minimum Circumscribed","Maximum Inscribed", "WeightedLeastSquares"];
        AssocData    fitType = [fitType.LeastSquares, ...
                                fitType.MinimumTotalDistance, fitType.ConstrainedLeastSquares, ...
                                fitType.ConstrainedMinimumTotalDistance, fitType.MiniMax, ...            
                                fitType.MinimumCircumscribed, fitType.MaximumInscribed, ...
                                fitType.WeightedLeastSquares];
        AssocEnabled logical = [true, false, false, false, false, false, false, false];   % only LS works today
        AssocPrev    fitType = fitType.LeastSquares; % last valid
        objName string = ""
        units string = "mm"
        lastFitTime double = NaN
        CurrentDataFolder string
        decPlaces (1,1) double = 4;
    end
    
    methods (Access = private)
        
        function validateData(app, data)
            if ~isnumeric(data) || size(data,2) ~= 3
                error('Data must be numeric Nx3.');
            end
            if any(~isfinite(data), 'all')
                error('Data contains NaN/Inf.');
            end            
        end

        function obj = constructFeature(app, geomName, data, assoc, name)
            switch geomName
                case "Line"
                    obj = Line(name, data, assoc);
                case "Plane"
                    obj = Plane(name, data, assoc);
                case "Circle"
                    obj = Circle(name, data, assoc);
                case "Sphere"
                    obj = Sphere(name, data, assoc);
                case "Cylinder"
                    obj = Cylinder(name, data, assoc);
                case "Cone"
                    obj = Cone(name, data, assoc);
                otherwise
                    error('Unsupported geometry: %s', geomName);
            end
        end

        function clearHighlight_(app)
            % hide the red marker
            if ~isempty(app.HighlightPt) && isvalid(app.HighlightPt)
                set(app.HighlightPt,'XData',NaN,'YData',NaN,'ZData',NaN);
            end
            % clear row tint
            try
                sc = app.DataTable.StyleConfigurations;
                for k = 1:numel(sc)
                    removeStyle(app.DataTable, sc(k).TargetStyle);
                end
            catch
            end
            % clear table selection & state
            try app.DataTable.Selection = []; end
            app.LastSelectedRow = [];
        end

        function AxesClicked_(app, ~)
            app.clearHighlight_();                 % always clear on axes background click
        end

        function FigureClicked_(app, ~)
            % Identify what was clicked
            obj = hittest(app.UIFigure);
        
            % If the click was on the DataTable, let the table callback handle it
            if isa(obj, 'matlab.ui.control.Table') || isequal(obj, app.DataTable)
                return;
            end
        
            % If the click happened on the overlay axes or any of its children, clear
            if isequal(obj, app.OverlayAxes) || ~isempty(ancestor(obj, 'matlab.ui.control.UIAxes'))
                app.clearHighlight_();
                return;
            end
        
            app.clearHighlight_();
        end

        function nm = getRequestedName(app, geomName)
            % Read, trim, and validate the user-provided name
            nm = strtrim(string(app.NameEditField.Value));
        
            % If empty, use the selected file
            if nm == ""
                if ~isempty(app.filePath)
                    [~, fname, ext] = fileparts(app.filePath);
                    nm = fname;    % e.g., 'cir10'
                else
                    nm = geomName;       % fallback
                end
            end
        
            % Clean whitespace
            nm = strrep(nm, sprintf('\n'), ' ');
            nm = regexprep(nm, '\s+', ' ');
        end

        function refreshAssocDropdown(app)
        
            geom = app.GeometryDropDown.Value;
        
            % Full list
            names = app.AssocNames;
            data  = app.AssocData;
            en    = app.AssocEnabled;   % [true false false false]
        
            switch geom
                case {"Line","Plane","Cone"}
                    keep = [1 2];      % keep LS + Minimax
                case {"Circle","Sphere","Cylinder"}
                    keep = [1 2 3 4];  % show all four
                otherwise
                    keep = 1;          % fallback
            end
        
            % Filter lists
            names = names(keep);
            data  = data(keep);
            en    = en(keep);
        
            % Append Unavailable to disabled items
            displayNames = names;
            for i = 1:numel(names)
                if ~en(i)
                    displayNames(i) = displayNames(i) + "  (Unavailable)";
                end
            end
        
            % Update dropdown

            app.AssociationDropDown.Items     = cellstr(displayNames);
            app.AssociationDropDown.ItemsData = num2cell(data);
        
            % Ensure current value is valid for this geometry
            if ~any(data == app.assoc)
                app.assoc = fitType.LeastSquares;
            end
            app.AssociationDropDown.Value = app.assoc;
        end


        function T = buildExportFromDisp(app)
            if isempty(app.obj) || ~isa(app.obj, 'Feature')
                error("Nothing to export: fit an object first.");
            end
        
            % Capture disp text and split into lines
            raw = evalc('disp(app.obj)');
            lines = strsplit(strtrim(raw), newline);
        
            keys = strings(0,1);
            vals = strings(0,1);
        
            for i = 1:numel(lines)
                line = string(strtrim(lines{i}));
                if line == ""
                    continue
                end
        
                % Match "Something Object" as a header line
                if endsWith(line, " Object")
                    keys(end+1,1) = "Object"; 
                    vals(end+1,1) = line;      
                    continue
                end
        
                % Match "Key: Value" (split only on the first colon)
                tokens = regexp(line, '^\s*([^:]+):\s*(.*)$', 'tokens', 'once');
                if ~isempty(tokens)
                    keys(end+1,1) = string(strtrim(tokens{1})); 
                    vals(end+1,1) = string(strtrim(tokens{2})); 
                else
                    % Fallback: put the whole line as a value with empty key
                    keys(end+1,1) = "";         
                    vals(end+1,1) = line;       
                end
            end        

            if ~isnan(app.lastFitTime)
                keys(end+1,1) = "Fit time"; 
                vals(end+1,1) = sprintf('%.4f s', app.lastFitTime); 
            end
        
            T = table(keys, vals, 'VariableNames', {'Key','Value'});
        end

        function txt = formatObjectDetails(app, obj, t_fit)
            % FORMATOBJECTDETAILS  Format disp() text from geometry object
            % with alignment and decimal precision control.
            %
            % Parameters:
            %   app   - the app instance
            %   obj   - fitted geometry object (Feature subclass)
            %   t_fit - fit time in seconds
        
            % Get disp() output
            raw = strsplit(strtrim(evalc('disp(obj)')), newline);
        
            % Parse into key-value pairs (handles wrapped lines)
            pairs = struct('k',{},'v',{});
            k = string.empty; v = string.empty;
            for i = 1:numel(raw)
                L = strtrim(string(raw{i}));
                tok = regexp(L, '^([A-Za-z][\w \/]+):\s*(.*)$', 'tokens', 'once');
                if ~isempty(tok)
                    if ~isempty(k)
                        pairs(end+1) = struct('k',k,'v',strtrim(v)); %#ok<AGROW>
                    end
                    k = string(tok{1});
                    v = string(tok{2});
                else
                    if isempty(v)
                        v = L;
                    else
                        v = strtrim(v + " " + L);
                    end
                end
            end
            if ~isempty(k)
                pairs(end+1) = struct('k',k,'v',strtrim(v));
            end
        
            % Skip reformatting for textual fields
            skipReformat = ["Name","AssociationType","Association Criteria","Units"];
            numPat = '([-+]?(?:\d+\.\d*|\.\d+|\d+)(?:[eE][+-]?\d+)?)';
            fmt = sprintf('%%.%df', app.decPlaces);
        
            % --- helper function for type-safe conversion
            function c = convertChars(v)
                if isa(v, "string")
                    c = char(v);
                elseif iscell(v)
                    c = char(v{1});
                elseif ischar(v)
                    c = v;
                else
                    c = char(string(v));
                end
            end
        
            % Reformat numeric values with precision
            for i = 1:numel(pairs)
                if any(strcmpi(pairs(i).k, skipReformat))
                    continue
                end
                try
                    vchar = convertChars(pairs(i).v);
                    vchar = regexprep(vchar, numPat, @(m) sprintf(fmt, str2double(m{1})));
                    pairs(i).v = string(vchar);
                catch
                    % silently skip if regex fails
                end
            end
        
            % Build aligned output text
            labels = string({pairs.k}) + ":";
            w = max(strlength(labels));
            out = strings(0,1);
            for i = 1:numel(pairs)
                out(end+1,1) = sprintf('%-*s %s', w, labels(i), pairs(i).v); %#ok<AGROW>
            end
            out(end+1,1) = sprintf('%-*s %.4f s', w, 'Fit time:', t_fit);
        
            % Return as a single text block
            txt = strjoin(out, newline);
        end
        % ------- Modification start ------- 3/25/2026
        % Load data function
        function data = loadPointData(app, filePath)
            [~, ~, ext] = fileparts(filePath);
            ext = lower(string(ext));
        
            switch ext
                case {".txt", ".ds", ".fit"}
                    data = readmatrix(filePath, FileType="text");
                otherwise
                    error("Unsupported file extension: %s", ext);
            end
        
            % Remove rows that are completely missing, if any
            if ~isempty(data)
                data = data(~all(isnan(data), 2), :);
            end
        end
        % ------- Modification end ------- 3/25/2026
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.assoc = fitType.LeastSquares;
            app.FolderEditField.Value = "";
            app.FolderEditField.FontAngle = 'normal';
            app.FolderEditField.FontColor = [0 0 0];
            app.FilesListBox.Items = {''};
            app.FilesListBox.FontAngle = 'italic';
            app.FilesListBox.FontColor = [0.6 0.6 0.6];
            app.DetailsTextArea.Value = "Ready";
            app.objName = "";
            cla(app.OverlayAxes, 'reset');
            grid(app.OverlayAxes, 'on');
            view(app.OverlayAxes, 3);

            app.DetailsTextArea.FontName = 'Courier New';
            try
                app.DetailsTextArea.WordWrap = 'off';   % R2020b+; harmless if not present
            catch
            end

            % Clear geometry dropdown
            app.GeometryDropDown.ValueChangedFcn = [];
            app.GeometryDropDown.Items = {'','Line', 'Plane', 'Circle', 'Sphere', 'Cylinder', 'Cone'};
            app.GeometryDropDown.ItemsData = ["","Line", "Plane", "Circle", "Sphere", "Cylinder", "Cone"];
            app.GeometryDropDown.Value = "";

            app.GeometryDropDown.ValueChangedFcn = createCallbackFcn(app, @GeometryDropDownValueChanged, true);

            % Initialize Decimals dropdown
            app.DecimalsDropDown.Items = arrayfun(@num2str, 2:10, 'UniformOutput', false);
            app.DecimalsDropDown.Value = '4';
            app.decPlaces = 4;

            % Update UI state            
            app.DetailsTextArea.Value = {'Ready.'};
            title(app.OverlayAxes, 'No selection yet');
            cla(app.OverlayAxes, 'reset'); grid(app.OverlayAxes, 'on'); view(app.OverlayAxes, 3);
        
            app.FolderEditFieldLabel.Visible    = "off";
            app.FilesListBoxLabel.Visible       = "off";
            app.GeometryDropDownLabel.Visible   = "off";
            app.DetailsTextAreaLabel.Visible    = "off";
            app.FitButton.Enable = 'off';
        
            app.OverlayAxes.ButtonDownFcn     = createCallbackFcn(app, @AxesClicked_, true);
            app.UIFigure.WindowButtonDownFcn  = createCallbackFcn(app, @FigureClicked_, true);
            app.AssociationDropDown.ValueChangedFcn = createCallbackFcn(app, @AssociationDropDownValueChanged, true);
            app.refreshAssocDropdown();
            app.UnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @UnitsDropDownValueChanged, true);
            app.ExportcsvButton.ButtonPushedFcn = createCallbackFcn(app, @ExportcsvButtonPushed, true);
            app.SaveMatButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMatButtonPushed, true);
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            folder = uigetdir(pwd, 'Select the folder containing coordinate data');
            if isequal(folder, 0)
                uialert(app.UIFigure, 'Selection canceled.', 'Info');
                return;
            end
            % Reset app state
            app.dataRoot = string(folder);
            app.filePath = "";
            app.obj = [];
            app.FitButton.Enable = 'off';

            % UI updates
            app.FolderEditField.Value = app.dataRoot;
            %files = dir(fullfile(app.dataRoot, '*.txt'));
            % app.FilesListBox.Items = {files.name};
            % app.FilesListBox.Value = {};
            % 
            % % Clear plot and details
            % cla(app.OverlayAxes);
            % grid(app.OverlayAxes, 'on');
            % view(app.OverlayAxes,3);
            % title(app.OverlayAxes, 'Overlay');
            % app.DetailsTextArea.Value = "Folder selected. Choose a file.";
            % ------- Modification start ------- 3/25/2026
            % Modification to accept all sorts of files
            % Get supported files
            allFiles = dir(app.dataRoot);
            allFiles = allFiles(~[allFiles.isdir]);   % remove folders
        
            fileNames = string({allFiles.name});
            isSupported = endsWith(fileNames, [".txt", ".ds", ".fit"], 'IgnoreCase', true);
            fileNames = sort(fileNames(isSupported));
        
            if isempty(fileNames)
                app.FilesListBox.Items = {};
                app.FilesListBox.Value = {};
                app.DetailsTextArea.Value = "No supported files found in this folder.";
            else
                app.FilesListBox.Items = cellstr(fileNames);
                app.FilesListBox.Value = {};
                app.DetailsTextArea.Value = "Folder selected. Choose a file.";
            end
        
            % Clear plot and details
            cla(app.OverlayAxes);
            grid(app.OverlayAxes, 'on');
            view(app.OverlayAxes, 3);
            title(app.OverlayAxes, 'Overlay');
            % ------- Modification end ------- 3/25/2026
        end

        % Value changed function: FilesListBox
        function FilesListBoxValueChanged(app, event)
            selectedFile = app.FilesListBox.Value;
        
            if isempty(selectedFile)
                app.filePath = "";
                app.FitButton.Enable = 'off';
                return
            end
        
            % Build path directly from the folder the user selected
            app.filePath = fullfile(app.dataRoot, selectedFile);
        
            % Enable Fit only if geometry has already been selected
            if app.GeometryDropDown.Value ~= ""
                app.FitButton.Enable = 'on';
                app.DetailsTextArea.Value = "Ready. Press Fit.";
            else
                app.FitButton.Enable = 'off';
                app.DetailsTextArea.Value = "File selected. Now choose a geometry.";
            end
        end

        % Value changed function: GeometryDropDown
        function GeometryDropDownValueChanged(app, event)
            try
                geom = app.GeometryDropDown.Value;
        
                % Case: user cleared selection
                if geom == ""
                    cla(app.OverlayAxes,'reset');
                    grid(app.OverlayAxes,'on'); view(app.OverlayAxes,3);
                    app.DetailsTextArea.Value = "Ready.";
                    app.FitButton.Enable = 'off';
                    return;
                end
                
                % Reset association (does not affect data/file)
                app.AssocEnabled = [true, false, false, false];
                app.refreshAssocDropdown();
                app.obj = [];
        
                % Clear plot only
                cla(app.OverlayAxes);
                grid(app.OverlayAxes,'on');
                view(app.OverlayAxes,3);
                title(app.OverlayAxes, sprintf('Geometry: %s', geom));
        
                % -------- UI messaging logic --------
                if isempty(app.dataRoot)
                    app.DetailsTextArea.Value = [ ...
                        "Geometry selected: " + geom ; ...
                        "Next: Click Browse and select your folder." ];
                elseif isempty(app.filePath)
                    app.DetailsTextArea.Value = [ ...
                        "Geometry selected: " + geom ; ...
                        "Folder loaded: " + app.dataRoot ; ...
                        "Now select a file." ];
                else
                    app.DetailsTextArea.Value = [ ...
                        "Geometry selected: " + geom ; ...
                        "Folder: " + app.dataRoot ; ...
                        "File: " + string(app.filePath) ; ...
                        "Ready. Press Fit." ];
                end
        
                % -------- Fit button enable logic --------
                if ~isempty(app.dataRoot) && ~isempty(app.filePath)
                    app.FitButton.Enable = 'on';
                else
                    app.FitButton.Enable = 'off';
                end
        
            catch ME
                app.DetailsTextArea.Value = sprintf("⚠ Error:\n%s", ME.message);
                if ~isdeployed, rethrow(ME); end
            end
        end

        % Button pushed function: FitButton
        function FitButtonPushed(app, event)
            txt = "";
            try
                if isempty(app.filePath)
                    uialert(app.UIFigure, 'Select a file first.', 'Missing input.');
                    return;
                end
    
            geomName = app.GeometryDropDown.Value; 
            % Load & validate data
            %data = readmatrix(app.filePath);
            % ------- Modification start ------- 3/25/2026
            % Loading data via helper function
            data = app.loadPointData(app.filePath);
            app.validateData(data);
            name = app.getRequestedName(geomName);
            assoc = app.assoc;
    
            % Fit
            tic;
            app.obj = app.constructFeature(geomName, data, assoc, name);
            %app.obj.units = app.units;
            %app.obj.DisplayPrecision = app.decPlaces;
            t_fit = toc;
            app.lastFitTime = t_fit;
    
            % Plot fitted geometry
            cla(app.OverlayAxes, 'reset');
            grid(app.OverlayAxes, 'on');
            view(app.OverlayAxes, 3);
            app.obj.plot(ax = app.OverlayAxes);

            % Ensure background clicks hit the axes, not the children
            set(app.OverlayAxes, 'HitTest','on', 'PickableParts','all');
            ch = app.OverlayAxes.Children;
            for k = 1:numel(ch)
                try
                    set(ch(k), 'HitTest','off', 'PickableParts','none');  % let clicks pass through
                catch
                end
            end
            
            % Make the highlighter ignore clicks
            if ~isempty(app.HighlightPt) && isvalid(app.HighlightPt)
                set(app.HighlightPt, 'HitTest','off', 'PickableParts','none');
            end
    
            % Populate Data table
            app.Data = data;                  
            app.DataTable.Data = data;
            if size(data,2) == 3
                app.DataTable.ColumnName = {'X','Y','Z'};
            else
                app.DataTable.ColumnName = compose("Col%d", 1:size(data,2));
            end
    
            % Clear previous styling/selection
            % Fully remove any existing style configuration
            try
                sc = app.DataTable.StyleConfigurations;
                for k = 1:numel(sc)
                    removeStyle(app.DataTable, sc(k).TargetStyle);
                end
            catch
            end
    
            % Clear tracked style + selection state
            if ~isempty(app.RowStyle)
                try removeStyle(app.DataTable, app.RowStyle);
                catch
                end
                app.RowStyle = [];
            end
            app.LastSelectedRow = [];
            try app.DataTable.Selection = [];
            catch
            end
    
            % Prime style system once so the first real selection behaves
            try
                dummyStyle = uistyle('BackgroundColor',[1 1 1]); 
                addStyle(app.DataTable, dummyStyle, 'row', 1);
                removeStyle(app.DataTable, dummyStyle);
            catch
            end
    
            % Create/refresh the single highlight marker
            hold(app.OverlayAxes,'on');
            if isempty(app.HighlightPt) || ~isvalid(app.HighlightPt)
                app.HighlightPt = scatter3(app.OverlayAxes, NaN, NaN, NaN, 36, 'filled');
                app.HighlightPt.MarkerEdgeColor = 'k';
                app.HighlightPt.MarkerFaceColor = 'r';

                set(app.HighlightPt, 'HandleVisibility','off', 'DisplayName','');
            else           
                app.HighlightPt.Parent = app.OverlayAxes;
                set(app.HighlightPt,'XData',NaN,'YData',NaN,'ZData',NaN);

                set(app.HighlightPt, 'HandleVisibility','off', 'DisplayName','');
            end
            hold(app.OverlayAxes,'off');
    
            % Details panel
            app.DetailsTextArea.Value = app.formatObjectDetails(app.obj, app.lastFitTime);
            %app.DetailsTextArea.Value = sprintf("%s\nFit time: %.4f s", txt, t_fit);    
            catch ME
            uialert(app.UIFigure, ME.message, 'Error');
            end
        end

        % Cell selection callback: DataTable
        function DataTableCellSelection(app, event)
            % normalize event payload
            idx = [];
            if isprop(event,'Indices')
                idx = event.Indices;          % Newer MATLAB
            elseif isprop(event,'Selection')
                idx = event.Selection;        % Older MATLAB
            end        
            % nothing selected -> clear everything
            if isempty(idx)
                app.clearHighlight_();
                return
            end        
            % extract selected row
            r = idx(1,1);
            T = app.DataTable.Data;       
            if r < 1 || r > size(T,1) || size(T,2) < 3
                app.clearHighlight_();
                return
            end        
            % visually select entire row
            nCol = size(T,2);
            try
                app.DataTable.Selection = [repmat(r, nCol, 1), (1:nCol)'];
            catch
            end        
            % move red marker to that point
            pt = T(r,1:3);
            if ~isempty(app.HighlightPt) && isvalid(app.HighlightPt)
                set(app.HighlightPt, 'XData', pt(1), 'YData', pt(2), 'ZData', pt(3));
                uistack(app.HighlightPt, 'top');
            end        
            app.LastSelectedRow = r;
        end

        % Value changed function: AssociationDropDown
        function AssociationDropDownValueChanged(app, event)
            newVal = event.Value;                            % <-- enum (AssociationType)
            idx    = find(app.AssocData == newVal, 1);    
            % Block disabled selections
            if ~app.AssocEnabled(idx)
                uialert(app.UIFigure, "This association type is not implemented yet.", "Not Available","Icon","warning");
                app.AssociationDropDown.Value = app.AssocPrev;  % revert
                return
            end        
            % Accept and remember; keep a copy for Fit
            app.AssocPrev = newVal;
            app.assoc     = newVal;               
        end

        % Value changed function: UnitsDropDown
        function UnitsDropDownValueChanged(app, event)
            app.units = string(event.Value);    % store current selection
            % refresh details if object already fitted
            % if ~isempty(app.obj)
            %     txt = evalc('disp(app.obj)');
            %     app.DetailsTextArea.Value = txt;
            %end
        end

        % Button pushed function: ExportcsvButton
        function ExportcsvButtonPushed(app, event)
            try
                if isempty(app.obj) || ~isa(app.obj, 'Feature')
                    uialert(app.UIFigure, "Fit an object first.", "Nothing to export");
                    return
                end                
                o = app.obj;
                defName = sprintf("%s_%s.csv", char(o.name), class(o));        
                [file, path] = uiputfile({'*.csv','CSV file (*.csv)'}, ...
                                         'Export details as rows', defName);
                if isequal(file,0), return; end
                fcsv = fullfile(path, file);        
                T = app.buildExportFromDisp();     
                % Overwrite by default 
                writetable(T, fcsv);      
                uialert(app.UIFigure, "Export complete.", "Done", "Icon","success");
            catch ME
                uialert(app.UIFigure, ME.message, "Export failed", "Icon","error");
            end
        end

        % Button pushed function: SaveMatButton
        function SaveMatButtonPushed(app, event)
            try
                if isempty(app.obj) || ~isa(app.obj,'Feature')
                    uialert(app.UIFigure,"Fit an object first.","Nothing to save");
                    return
                end   
                o = app.obj;
                def = sprintf('%s_%s.mat', char(o.name), class(o));
                [file, path] = uiputfile({'*.mat','MATLAB file (*.mat)'}, 'Save geometry fit', def);
                if isequal(file,0), return; end
                fmat = fullfile(path, file);                
                savedObject = o;                         % full geometry object
                detailsText = evalc('disp(o)');         % exact text shown in Object Details
                fitTime     = app.lastFitTime;       
                sourceFile  = "";
                if ~isempty(app.filePath)
                    [~, fn, ext] = fileparts(app.filePath);
                    sourceFile = fn + ext;
                end  
                className = string(class(o));
                objName   = string(o.name);
                assocStr  = string(char(o.associationType));
                if isprop(o,'units')
                    unitsStr = string(o.units);
                else
                    unitsStr = "";
                end              
                sourceFile = char(sourceFile);
                className  = char(className);
                objName    = char(objName);
                assocStr   = char(assocStr);
                unitsStr   = char(unitsStr);  
                save(fmat, 'savedObject','detailsText','fitTime', ...
                          'sourceFile','className','objName','assocStr','unitsStr');        
                uialert(app.UIFigure,"MAT file saved.","Done","Icon","success");
        
            catch ME
                uialert(app.UIFigure, sprintf('%s\n(%s)', ME.message, ME.identifier), ...
                        "Save failed", "Icon","error");
            end
        end

        % Value changed function: DecimalsDropDown
        function DecimalsDropDownValueChanged(app, event)
            % Read and store the selected number of decimals
            val = str2double(event.Value);
            if isnan(val) || val < 2 || val > 10
                val = 4; % fallback
            end
            app.decPlaces = val;
        
            % Refresh Object Details if an object already exists
            if ~isempty(app.obj) && isa(app.obj, 'Feature')
                app.DetailsTextArea.Value = app.formatObjectDetails(app.obj, app.lastFitTime);
            end        
        end

        % Button pushed function: DownloadSampleDataButton
        function DownloadSampleDataButtonPushed(app, event)
            web('https://download-directory.github.io/?url=https%3A%2F%2Fgithub.com%2Fjaime-berez%2FOpenDiMet%2Ftree%2Fmain%2FData','-browser');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OverlayAxes = uiaxes(app.UIFigure);
            title(app.OverlayAxes, 'Overlay')
            xlabel(app.OverlayAxes, 'X')
            ylabel(app.OverlayAxes, 'Y')
            zlabel(app.OverlayAxes, 'Z')
            app.ControlsPanel = uipanel(app.UIFigure);
            app.ControlsPanel.Title = 'Controls';
            app.GeometryLabel = uilabel(app.ControlsPanel);
            app.GeometryLabel.Text = 'Geometry';
            app.GeometryDropDownLabel = uilabel(app.ControlsPanel);
            app.GeometryDropDownLabel.Text = 'GeometryDropDown';
            app.GeometryDropDown = uidropdown(app.ControlsPanel);
            app.GeometryDropDown.Items = {'Line', 'Plane', 'Circle', 'Sphere', 'Cylinder', 'Cone'};
            app.GeometryDropDown.ValueChangedFcn = createCallbackFcn(app, @GeometryDropDownValueChanged, true);
            app.GeometryDropDown.Value = 'Line';
            app.FitButton = uibutton(app.ControlsPanel, 'push');
            app.FitButton.ButtonPushedFcn = createCallbackFcn(app, @FitButtonPushed, true);
            app.FitButton.Text = 'Fit';
            app.ObjectDetailsLabel = uilabel(app.ControlsPanel);
            app.ObjectDetailsLabel.Text = 'Object Details';
            app.DetailsTextAreaLabel = uilabel(app.ControlsPanel);
            app.DetailsTextAreaLabel.Text = 'DetailsTextArea';
            app.DetailsTextArea = uitextarea(app.ControlsPanel);
            app.DetailsTextArea.Editable = 'off';
            app.DataFolderLabel = uilabel(app.ControlsPanel);
            app.DataFolderLabel.Text = 'Data Folder';
            app.FolderEditFieldLabel = uilabel(app.ControlsPanel);
            app.FolderEditFieldLabel.Text = 'FolderEditField';
            app.FolderEditField = uieditfield(app.ControlsPanel, 'text');
            app.FolderEditField.Editable = 'off';
            app.FilesListBoxLabel = uilabel(app.ControlsPanel);
            app.FilesListBoxLabel.Text = 'FilesListBox';
            app.FilesListBox = uilistbox(app.ControlsPanel);
            app.FilesListBox.Items = {};
            app.FilesListBox.ValueChangedFcn = createCallbackFcn(app, @FilesListBoxValueChanged, true);
            app.FilesListBox.Value = {};
            app.FilesLabel = uilabel(app.ControlsPanel);
            app.FilesLabel.Text = 'Files';
            app.BrowseButton = uibutton(app.ControlsPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.AssociationCriteriaLabel = uilabel(app.ControlsPanel);
            app.AssociationCriteriaLabel.Text = 'Association Criteria';
            app.AssociationDropDownLabel = uilabel(app.ControlsPanel);
            app.AssociationDropDownLabel.Text = 'AssociationDropDown';
            app.AssociationDropDown = uidropdown(app.ControlsPanel);
            app.AssociationDropDown.Items = {'Least Squares', 'Minimax', 'Minimum Circumscribed', 'Maximum Inscribed'};
            app.AssociationDropDown.ValueChangedFcn = createCallbackFcn(app, @AssociationDropDownValueChanged, true);
            app.AssociationDropDown.Value = 'Least Squares';
            app.NameOptionalLabel = uilabel(app.ControlsPanel);
            app.NameOptionalLabel.Text = 'Name (Optional)';
            app.NameEditFieldLabel = uilabel(app.ControlsPanel);
            app.NameEditFieldLabel.Text = 'NameEditField';
            app.NameEditField = uieditfield(app.ControlsPanel, 'text');
            app.UnitLabel = uilabel(app.ControlsPanel);
            app.UnitLabel.Text = 'Unit';
            app.UnitsDropDownLabel = uilabel(app.ControlsPanel);
            app.UnitsDropDownLabel.Text = 'UnitsDropDown';
            app.UnitsDropDown = uidropdown(app.ControlsPanel);
            app.UnitsDropDown.Items = {'µm', 'mm', 'cm', 'm', 'inch'};
            app.UnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @UnitsDropDownValueChanged, true);
            app.UnitsDropDown.Value = 'mm';
            app.ExportcsvButton = uibutton(app.ControlsPanel, 'push');
            app.ExportcsvButton.ButtonPushedFcn = createCallbackFcn(app, @ExportcsvButtonPushed, true);
            app.ExportcsvButton.Tag = 'ExportButton';
            app.ExportcsvButton.Text = 'Export .csv...';
            app.SaveMatButton = uibutton(app.ControlsPanel, 'push');
            app.SaveMatButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMatButtonPushed, true);
            app.SaveMatButton.Text = 'Save .mat...';
            app.DisplayDecimalLabel = uilabel(app.ControlsPanel);
            app.DisplayDecimalLabel.Text = 'Display Decimal';
            app.DecimalsDropDownLabel = uilabel(app.ControlsPanel);
            app.DecimalsDropDownLabel.Text = 'Decimals';
            app.DecimalsDropDown = uidropdown(app.ControlsPanel);
            app.DecimalsDropDown.Items = {'2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.DecimalsDropDown.ValueChangedFcn = createCallbackFcn(app, @DecimalsDropDownValueChanged, true);
            app.DecimalsDropDown.Tag = 'DecimalsDropDown';
            app.DecimalsDropDown.Value = '4';
            app.DownloadSampleDataButton = uibutton(app.ControlsPanel, 'push');
            app.DownloadSampleDataButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadSampleDataButtonPushed, true);
            app.DownloadSampleDataButton.Text = 'Download Sample Data';
            app.DataPanel = uipanel(app.UIFigure);
            app.DataPanel.Title = 'Data';
            app.DataPanel.Tag = 'DataPanel';
            app.DataTable = uitable(app.DataPanel);
            app.DataTable.ColumnName = {'X'; 'Y'; 'Z'};
            app.DataTable.RowName = {};
            app.DataTable.SelectionType = 'row';
            app.DataTable.CellSelectionCallback = createCallbackFcn(app, @DataTableCellSelection, true);
            app.DataTable.Multiselect = 'off';
            app.DataTable.Tag = 'DataTable';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OpenDiMetApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end